classdef nsGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeurostimUIFigure          matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        ChangeRootFolderMenu       matlab.ui.container.Menu
        EditExperimentFileMenu     matlab.ui.container.Menu
        ValidateBitsMenu           matlab.ui.container.Menu
        PreferencesMenu            matlab.ui.container.Menu
        FactoryResetMenu           matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        ProjectExperimentsLabel    matlab.ui.control.Label
        Root                       matlab.ui.control.EditField
        ExperimentSettingsPanel    matlab.ui.container.Panel
        Debug                      matlab.ui.control.CheckBox
        Subject                    matlab.ui.control.EditField
        SubjectEditFieldLabel      matlab.ui.control.Label
        Seq                        matlab.ui.control.NumericEditField
        SeqLabel                   matlab.ui.control.Label
        Run                        matlab.ui.control.NumericEditField
        RunEditFieldLabel          matlab.ui.control.Label
        Mode                       matlab.ui.control.DropDown
        ModeDropDownLabel          matlab.ui.control.Label
        GoButton                   matlab.ui.control.Button
        PluginSettingsPanel        matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        placeholder                matlab.ui.container.Tab
        Tab3                       matlab.ui.container.Tab
        Status                     matlab.ui.control.Label
        GitSettingsPanel           matlab.ui.container.Panel
        SilentCommit               matlab.ui.control.CheckBox
        GitSync                    matlab.ui.control.Switch
        Neurostim                  matlab.ui.control.EditField
        NeurostimEditFieldLabel    matlab.ui.control.Label
        Experiments                matlab.ui.control.EditField
        ExperimentsEditFieldLabel  matlab.ui.control.Label
    end


    properties (Constant)
        onColor = [0.3 0.75 0.93]   % Background color for a plugin that is on
        offColor = 0.94 * [1 1 1];  % Background color for a plugin that is off
        fakeColor = [0.7 0.7 0.1]   % Background color for a plugin that is in fake mode
        prefGroup = 'nsGui';
        PanelPosition = [1 1 610 77]; % Default position of panel; used by plugin.guiLayout
    end
    properties (Access = private)
        hSetAndRun; % Handle to the set and start app

        % These items in the gui will be saved and restored on each session
        savedStateFields = {'Experiments','Neurostim','GitSync','Root'};
        savedStateFieldsPerExperiment= {'Mode','Subject','Run','Seq','Debug'};

        % Preferences can be set in Preferences menu and are also restored
        % each session
        % Wildcard: used to identify files that can be run by the gui
        % MyRig : The user/rig/lab specific file that creates a CIC object
        % with default settings
        % PerSubject :Toggle to indicate whether State (i.e. plugin
        % settings) should be remembered per subject.
        preferences = struct('Wildcard','**/run*.m','MyRig','myRig','PerSubject',false);

        folders; % Description
        files;
        machine; % Description
        cic % handle to the currently running cic

        settings; % settings struct passed to the experiment run file
    end

    properties (Dependent)
        isFirstTime;
        uFolders;
        currentExperiment;
        currentExperimentFile;
        experimentsFolder;
    end

    methods  % get/set methods

        function v=get.uFolders(app)
            v= unique(app.folders);
        end

        function v = get.experimentsFolder(app)
            v =fileparts(app.currentExperimentFile);
        end

        function v=get.currentExperimentFile(app)
            % Determine what the currently seleted file is.
            if ~isempty(app.TabGroup.SelectedTab)
                folder = app.TabGroup.SelectedTab.Title;
                if ~isempty(app.TabGroup.SelectedTab.Children)
                    file = app.TabGroup.SelectedTab.Children.Value;
                    v = fullfile(folder,  file);
                else
                    v= '';
                end
            else
                v = '';
            end
        end

        function fun = get.currentExperiment(app)
            % Create a handle to the experiment file
            [pth,f] =fileparts(app.currentExperimentFile);
            pthWithoutNs = extractBefore(pth,'+');
            if isempty(pthWithoutNs)
                % no +
                pthWithoutNs = pth;
            end
            cd(fullfile(app.Root.Value,pthWithoutNs));
            ns = extractAfter(pth,'+');
            if ~isempty(ns)
                exe = [ns '.' f];
            else
                exe  = f;
            end
            fun = str2func(exe);
        end
    end


    methods (Access = private)

        function toggle(app,on)
            if on
                app.GoButton.Text ='Go';
                app.GoButton.Enable = true;
            else
                app.GoButton.Text ='Please wait...';
                app.GoButton.Enable = false;
            end
        end

        function updatePlugins(app)
            % Update the plugins panel by calling the selected experiment file and setting options
            % accordingly.
            delete(app.PluginSettingsPanel.Children)
            try
                [p] =app.currentExperiment('do','qry');
            catch
                app.Mode.Items ={};
                if isempty(app.currentExperimentFile)
                    txt = ' No files found. Use File | Change Root Folder  to go to your experiment folders';
                else
                    txt = [app.currentExperimentFile ' has errors or it is not gui-enabled'];
                end
                uilabel(app.PluginSettingsPanel,'Text',txt,'Position',[1 round(app.PluginSettingsPanel.Position(4)/2) 450 22],'FontColor',[1 0 0 ]);
                return;
            end
            currentBottom=app.PluginSettingsPanel.Position(4)-20;
            %% Add gui panels for each of the requested plugins

            out =cellfun(@isempty,p.panels);
            nonEmptyPanels = p.panels(~out);
            for plg=nonEmptyPanels
                if ischar(plg{1})
                    % The experiment file specified a named plugin - try neurostim namespace first
                    fun = ['neurostim.' plg{1} '.guiLayout'];
                    ok = which(fun);
                    if isempty(ok)
                        % Then try global namespace
                        fun = [plg{1} '.guiLayout'];
                        ok = which(fun);
                    end
                    if ok
                        % Create the standard wrapping uipanel
                        pnl= uipanel(app.PluginSettingsPanel,'Tag',plg{1});
                        pnl.Title = plg{1};
                        pnl.FontWeight = 'Bold';
                        pnl.Position = nsGui.PanelPosition;
                        pnl.UserData.create = true; % Indicate that this plugin will have to created by the GUI.(seet SetupCIC)
                        % Add a knob
                        h = uiknob(pnl, 'discrete');
                        h.Tag = 'onOffFakeKnob';
                        h.Items = {'Off', 'Fake', 'On'};
                        h.Position = [30 8 31 31];
                        h.ValueChangedFcn = @nsGui.colorState;
                        % Call the plugin defined guiLayout function to add other elements(if defined).
                        feval(fun,pnl); % eg. neurostim.plugins.eyelink.guiLayout(p) , a static function
                    else
                        % Alert
                        uialert(app.NeurostimUIFigure,[app.currentExperimentFile ' specified an incorrect panel (' plg{1} ')'],'Error');
                    end
                elseif isa(plg{1},'function_handle')
                    % This is a handle to a function that creates a user defined gui . Add it into the GUI.
                    pnl= uipanel(app.PluginSettingsPanel); % Create a panel
                    pnl.FontWeight = 'Bold';
                    pnl.Position = nsGui.PanelPosition;
                    pnl.UserData.create =false; % This panel is used by the user (inside the experiment script)
  
                    % Call the user defined function with the panel as parent.
                    plg{1}(pnl);
                    % The user defined function must set the Tag to either experiment
                    % or the name of the corresponding stimulus/plugin (such that
                    % at runtime cic.(pnl.Tag) is a handle to the plugin).
                    if isempty(pnl.Tag) || contains(pnl.Tag,'.')
                        uialert(app.NeurostimUIFigure,[func2str(plg{1}) ' in ' app.currentExperimentFile ' does not set Tag correctly: ' plg.Tag],'Error');
                    end
                else
                    plg{1} %#ok<NOPRT>
                    uialert(app.NeurostimUIFigure,[app.currentExperimentFile ' specified an incorrect panel '],'Error');
                    return;
                end
                % For every element of the newly addded panel that has a
                % tag, add a handler to do live updates.
                for h=pnl.Children'
                    if ~isempty(h.Tag) && isfield(h,'ValueChangedFcn')
                        h.ValueChangedFcn = @(hh,evt) nsGui.uiElementValueChangedCallback(hh,evt);
                    end
                end

                % Position the panel (stack)
                pnl.Position(1) = 1;
                pnl.Position(2) = currentBottom-pnl.Position(4);
                currentBottom = pnl.Position(2);
            end
            %% Set the allowed modes
            app.Mode.Items = p.modes;
            app.Mode.Enable = true;

            %% Disable some field if requested
            app.Run.Enable= true;
            app.Seq.Enable = true;
            app.Debug.Enable = true;
            app.GoButton.Enable = true;

            for fld = p.disabled
                switch upper(fld{1})
                    case 'RUN'
                        app.Run.Value =0;
                        app.Run.Enable =false;
                    case 'SEQ'
                        app.Seq.Value= 0;
                        app.Seq.Enable =false;
                    case 'DEBUG'
                        app.Debug.Value = false;
                        app.Debug.Enable = false;
                    case 'ALL'
                        app.Mode.Items = {'DISABLED'};
                        app.Mode.Enable = false;
                        app.GoButton.Enable = false;
                end
            end
            loadStateExp(app); % Load for current experiment
            loadStateMode(app); % Load for current mode.
        end

        function updateExperimentList(app)
            delete(get(app.TabGroup,'Children'));
            for grp=1:numel(app.uFolders)
                thisExperiments = app.files(strcmpi(app.uFolders{grp},app.folders));
                h = uitab(app.TabGroup,'title',app.uFolders{grp});
                width = 0.9*h.Position(3);
                height = 0.95*h.Position(4);
                uilistbox('Parent', h, ...
                    'Position',[15 15 width height],...
                    'Items',thisExperiments,...
                    'ValueChangedFcn', @app.listBoxSelectionChanged);
            end
            drawnow;
        end

        function gotoFolder(app,newRoot)
            toggle(app,false);
            if nargin>1
                % Call from the menu
                app.Root.Value = newRoot;
            end
            if ~exist(app.Root.Value,'dir')
                app.Root.Value = fileparts(mfilename('fullpath'));
            end
            setpref(nsGui.prefGroup,'Root',app.Root.Value );
            cd(app.Root.Value);
            app.Root.Tooltip = app.Root.Value;

            setStatus(app,['Changing to folder :'  app.Root.Value]);
            gitcheckout(app,'Experiments',app.Root.Value);
            setStatus(app,'Git Sync Complete');

            scanForExperiments(app);
            updateExperimentList(app);
            updatePlugins(app);

            setStatus(app, 'Folder read completed.');
            toggle(app,true);
        end


        function  bitsppValidate(app,forceValidate)
            if nargin <2
                forceValidate = false;
            end
            setStatus(app,'Validating Bits++ setup');
            % Check that PTB has been setup properly
            if forceValidate || ~exist(fullfile(PsychtoolboxConfigDir,'ptbbitsplusplusvalidationfile.txt'),'file')
                % Definitely not validated.
                disp('We need to validate the VPixx setup.');
                disp('Answer the following questions with ''d'' (Test with datapixx) and then ''n'' (Run datapixx diagnostics)');
                screen = input(['Which screen ' num2str(Screen('Screens')) ' is the VPixx? '],'s');
                screen = str2double(screen);
                % Test the pipeline
                BitsPlusImagingPipelineTest(screen);
                % And the Clut
                BitsPlusIdentityClutTest(screen, 1, [], 1);
            else
                disp('This setup has been validated at least once.')
            end
            setStatus(app, 'Completed Bits++ validation');
        end


        function saveState(app,exp,mode)
            if nargin<3
                mode ='';
                if nargin <2
                    exp = '';
                end
            end
            % Save nsGui state.
            for i=1:numel(app.savedStateFields)
                setpref(nsGui.prefGroup,app.savedStateFields{i},app.(app.savedStateFields{i}).Value );
            end

            % Save preferences
            setpref(nsGui.prefGroup,'preferences',app.preferences);

            % Save state per experiment
            if isempty(exp)
                exp = app.currentExperimentFile;
            end
            if isempty(mode)
                mode = app.Mode.Value;
                if isempty(mode)
                    mode = 'empty';
                end
            end

            % Set the chosen mode for an experiment
            [~,e] = fileparts(exp);
            for i=1:numel(app.savedStateFieldsPerExperiment)
                setpref(nsGui.prefGroup,[e app.savedStateFieldsPerExperiment{i}],app.(app.savedStateFieldsPerExperiment{i}).Value);
            end

            if app.preferences.PerSubject
                % State stored per subject
                subject = app.Subject.Value;
            else
                subject =''; % means not per subject
            end

            % Save state for a specific experiment/mode.
            for plg = app.PluginSettingsPanel.Children'
                if ~strcmpi(plg.Type,'uipanel') || isempty(plg.Tag) ;continue;end  % Plugins must have uipanel and a tag (name)
                if ispref(nsGui.prefGroup,'map')
                    map = getpref(nsGui.prefGroup,'map');
                else
                    map =containers.Map;
                end
                for f = plg.Children'
                    if ~isempty(f.Tag)
                        key = nsGui.getKey(app.Root.Value,exp,mode,plg.Tag,f.Tag,subject);
                    end
                    m = metaclass(f);
                    if ismember('Value',{m.PropertyList.Name})
                        map(key) = f.Value;
                    end
                    if ismember('Data',{m.PropertyList.Name})    % Uitable has .Data
                        map(key) = f.Data;
                    end
                end
                setpref(nsGui.prefGroup,'map',map);
            end

        end

        function loadState(app)
            % Load base nsGui State (without knowing exp/mode)
            for i=1:numel(app.savedStateFields)
                if ispref(nsGui.prefGroup,app.savedStateFields{i})
                    app.(app.savedStateFields{i}).Value = getpref(nsGui.prefGroup,app.savedStateFields{i});
                end
            end
            % Load preferences
            if ispref(nsGui.prefGroup,'preferences')
                storedPref = getpref(nsGui.prefGroup,'preferences');
                fn  = fieldnames(storedPref);
                for i=1:numel(fn)
                    app.preferences.(fn{i}) = storedPref.(fn{i});
                end
            end
        end

        function loadStateExp(app,exp)
            % Load state once exp is known
            if nargin <2
                exp = '';
            end


            if isempty(exp)
                exp = app.currentExperimentFile;
            end
            [~,e] = fileparts(exp);
            for i=1:numel(app.savedStateFieldsPerExperiment)
                if ispref(nsGui.prefGroup,[e app.savedStateFieldsPerExperiment{i}])
                    thisState = getpref(nsGui.prefGroup,[e app.savedStateFieldsPerExperiment{i}]);
                else
                    thisState = app.(app.savedStateFieldsPerExperiment{i}).Value;
                end
                if strcmpi(app.savedStateFieldsPerExperiment{i},'Mode')
                    if  ismember(thisState,app.Mode.Items)
                        app.Mode.Value = thisState;
                    end
                else
                    app.(app.savedStateFieldsPerExperiment{i}).Value = thisState;
                end
            end
        end

        function loadStateMode(app,mode)
            % Load state once exp and mode are known
            if nargin<2
                mode = app.Mode.Value;
            end

            exp = app.currentExperimentFile;
            if ispref(nsGui.prefGroup,'map')
                map = getpref(nsGui.prefGroup,'map');
            else
                return;
            end

            if app.preferences.PerSubject
                % State stored per subject
                subject = app.Subject.Value;
            else
                subject ='';
            end


            for plg = app.PluginSettingsPanel.Children'
                if ~strcmpi(plg.Type,'uipanel') || isempty(plg.Tag) ;continue;end  % Plugins must have uipanel and a tag (name)
                for f = plg.Children'
                    if isempty(f.Tag)
                        continue;
                    else
                        key = nsGui.getKey(app.Root.Value,exp,mode,plg.Tag,f.Tag,subject);
                    end
                    if isKey(map,key)
                        storedValue = map(key);
                        m = metaclass(f);
                        if ismember('Value',{m.PropertyList.Name})
                            try
                                f.Value = storedValue;
                                if isa(f.ValueChangedFcn,'function_handle')
                                    e =matlab.ui.eventdata.ValueChangedData(storedValue,f.Value);
                                    f.ValueChangedFcn(f,e);
                                end
                            catch me %#ok<NASGU>
                                % probably no longer a valide dropdown .
                                % Ignore.Start from scratch with default
                            end
                        elseif ismember('Data',{m.PropertyList.Name})
                            okToReplace = true;
                            % Chcek that the stored table and the table
                            % currently in the GUI (i.e. the one hardcoded  in the guiPanel)
                            % match in terms of number of columns, and the categories of categorical
                            % variables. If they don't ignore the
                            % storedValues (i.e. start from scratch with
                            % the hard coded/default  values).
                            if width(f.Data) == width(storedValue) && all(ismember(f.Data.Properties.VariableNames,storedValue.Properties.VariableNames))
                                for i=1:width(storedValue)
                                    if iscategorical(storedValue{:,i})
                                        hasNewCategories= ~isempty(setdiff(categories(f.Data{:,i}),categories(storedValue{:,i})));
                                        okToReplace = ~hasNewCategories;
                                    end
                                end
                            else
                                okToReplace = false;
                            end
                            if okToReplace
                                f.Data = storedValue;
                            end
                        end
                    end
                end
            end
        end


        function gitcheckout(app,repo,folder)
            if strcmpi(app.GitSync.Value,'Off')
                return;
            end
            repoBranch = app.(repo).Value;
            setStatus(app,['Updating ' repo  ' files']);
            app.(repo).Value = neurostim.utils.git.checkout('branch',repoBranch,'repoFolder',folder);
            setStatus(app,['Updated ' repo  ' files']);
        end


        function factoryReset(app)
            prefs = fieldnames(getpref);
            kPrefs = strncmpi(prefs,app.prefGroup,numel(app.prefGroup));
            prefs = prefs(kPrefs);
            for i= 1:numel(prefs)
                rmpref(prefs{i});
            end
            event.EventName ='CloseWithoutSave';
            NeurostimUIFigureCloseRequest(app,event);
        end

        function  scanForExperiments(app)
            lst =dir(fullfile(app.Root.Value,app.preferences.Wildcard));
            app.folders = strrep({lst.folder},app.Root.Value,'');
            app.files =   {lst.name};
        end


        function setStatus(app,text)
            app.Status.Text = [app.machine ':' text];
        end

        function setPreferences(app)
            % Set preferences (called from Preferences Menu)
            parms = fieldnames(app.preferences);
            current = struct2cell(app.preferences);

            islog  = cellfun(@islogical,current);
            isnum = cellfun(@isnumeric,current);
            current(isnum|islog) = cellfun(@num2str,current(isnum | islog),'Uniform',false);

            nrLines =1;
            options.WindowStyle = 'modal';
            options.Resize = 'off';
            options.Interpreter = 'none';
            answer =inputdlg(parms,'nsGui Preferences',nrLines,current,options);
            if isempty(answer)
                % Cancel. No Change
            else
                answer(isnum) = cellfun(@str2num,answer(isnum),'Uniform',false);
                answer(islog) = cellfun(@(x)(logical(str2num(x))),answer(islog),'Uniform',false); %#ok<ST2NM>
                app.preferences = cell2struct(answer,parms);
                % Goto the folder to rescan
                gotoFolder(app);
            end
        end

        function listBoxSelectionChanged(app,~,event)
            folder = app.TabGroup.SelectedTab.Title; %
            file = event.PreviousValue;
            exp  = fullfile(folder,  file);
            saveState(app,exp,'');
            updatePlugins(app);
        end

        function setupCic(app)
            % Setup cic with appropriate plugins
            % Create a default CIC
            app.cic = neurostim.cic;
            % Call versionTracker to store experiments repository info
            exptGitTracker = struct('on',app.Debug.Value ==0 && strcmpi(app.GitSync.Value,'On'),'silent',app.SilentCommit.Value,'commit',true,'folder',pwd);
            app.cic.repoVersion = neurostim.utils.git.versionTracker(exptGitTracker);
            % Setup cic gitTracker which will be caled by cic.rn
            app.cic.gitTracker = struct('on',app.Debug.Value ==0 && strcmpi(app.GitSync.Value,'On'),'silent',app.SilentCommit.Value,'commit',true);

            %% Settings applied to CIC
            app.cic.runNr =app.Run.Value;
            app.cic.seqNr = app.Seq.Value;
            [nr] = str2double(app.Subject.Value);
            if ~isnan(nr)
                app.cic.subjectNr = nr;
            else
                app.cic.subjectNr = double(app.Subject.Value);
            end

            %% Add-in the plugins and stimuli as requested in the experiment file
            for pnl =app.PluginSettingsPanel.Children'
                if ~strcmpi(pnl.Type,'uipanel') || isempty(pnl.Tag) ;continue;end  % Plugins must have uipanel and a tag (name) that matches the name of the plugin
                tags = cell(1,numel(pnl.Children));
                for i=1:numel(pnl.Children)
                    tags{i}= pnl.Children(i).Tag;
                end
                knob = strcmpi(tags,'OnOffFakeKnob');
                if any(knob) && strcmpi(pnl.Children(knob).Value,'Off');continue;end % Plugin Not used in this run

                thisParms = nsGui.getParms(pnl);
                thisParms.hPnl = pnl;
                % The Tag is either plugins.xxxx or stimuli.xxx for panels
                % defined in the fixed source code of plugins/stimuli, or
                % it is one of the AdHoc UI, defined by a separate
                % function, usually in the experiment file.
                tags = strsplit(pnl.Tag,'.');
                if pnl.UserData.create 
                    % plugins.xxx or stimuli.xxx  or just xxx
                    % Create plugin, then apply settings to plugin (i.e. uses the guiSet of the plugin)
                    if numel(tags)>1
                        % From plugins or stimuli package - preface with
                        % neurostim
                        fullName = ['neurostim.' pnl.Tag];                        
                    else
                        fullName = pnl.Tag; % Somewhere else on the path
                    end
                    thisPlg = feval(fullName,app.cic);% Create the plugin and add it to CIC
                    pnl.UserData.plugin = thisPlg; % Put a handle to the plugin in userdata
                    guiSet(thisPlg,thisParms); % Call the plugin's guiSet function to interpret the GUI settings
                    if numel(tags)>1
                        app.settings.(tags{1}).(tags{2})= thisParms; % Pass to experiment function too
                    else   
                        app.settings.(tags{1})= thisParms; % Pass to experiment function too
                    end
                else
                    fn = fieldnames(thisParms);
                    val = struct2cell(thisParms);
                    if strcmpi(pnl.Tag,'experiment')
                        % This is a panel that was defined for a specific
                        % experiment file (i.e. not a fixed part of a plugins
                        % source).
                        % The parameters in this panel are only used in the experiment file,
                        % at the start of the experiment; they do not update during the experiment.
                        % The values are available in the experiment file as
                        % settings.xxx
                        for i=1:numel(fn)
                            app.settings.(fn{i}) = val{i}; % Add to the .settings member
                        end
                        pnl.UserData.plugin = []; % No live updates
                    else
                        % This is a panel that targets properties of a plugin/stimulus yet to be
                        % created (in the experiment file).
                        % The values are available in the experiment file as
                        % settings.(plg.Tag).xxx
                        for i=1:numel(fn)
                            app.settings.(pnl.Tag).(fn{i}) = val{i}; % Add to the .settings.target member
                        end
                        pnl.UserData.cic = app.cic;
                        pnl.UserData.plugin = pnl.Tag; % Need this for live updates.
                    end
                end
            end

            %% Pass this cic to the user-specified myRig function for rig/lab dependent settings
            ok = which(app.preferences.MyRig);
            if isempty(ok)
                error(['The myRig function (' app.preferences.MyRig ') could not be found on the path']);
            else
                fun =str2func(app.preferences.MyRig);
                args = {'debug',app.Debug.Value};
                app.cic = fun('cic',app.cic,args{:});
            end


            app.settings.mode = app.Mode.Value; %
            app.settings.debug = app.Debug.Value;

            % Now app.cic is setup with GUI defined properties as well as rig/lab dependent settings.
        end
    end

    methods (Static)

        function thisParms = getParms(plg)
            thisParms = struct;
            for f = plg.Children'
                m = metaclass(f);
                if ~isempty(f.Tag)
                    if ismember('Value',{m.PropertyList.Name})
                        thisParms.(f.Tag) = f.Value; % Usually the caller needs the value
                    elseif ismember('Data',{m.PropertyList.Name})
                        thisParms.(f.Tag) = f.Data; %  or the data.
                    else
                        thisParms.(f.Tag) = f; % If there is no value, pass the handle to uiobject
                    end
                end
            end
        end
        function colorState(o,e)
            switch e.Value
                case 'On'
                    o.Parent.BackgroundColor = nsGui.onColor;
                case 'Off'
                    o.Parent.BackgroundColor = nsGui.offColor;
                case 'Fake'
                    o.Parent.BackgroundColor = nsGui.fakeColor;
            end
        end


        function uiElementValueChangedCallback(h,evt)
            % Note that this is a callback for the elements in the panels,
            % not the nsGui itself.
            %
            % For each ui element in each plugin panel, this callback is installed
            % to update values in the corresponding plugin while the nsGui is running.
            % See nsGui.updatePlugins
            %
            % Because the Go Button callback (i.e. the main experiment) takes priority, this
            % callback only executes at somewhat unpredictable times
            % (idle). So this should not be used for time sensitive
            % controls.
            %
            % h = handle to the ui element that changed.

            % Find the relevant plugin from the parent, get all of its
            % parms from the gui, and send it to the plugin's guiSet
            % function. Parameters that did not change in the gui will be
            % assigned again, but because NS ignores that, this is harmless
            % (and allows reuse of the guiSet function).

            plgPanel = h.Parent;
            if isstruct(plgPanel.UserData)
                % AdHocUI created by some function in the
                % experiment.Have to construct the handle to the plugin
                % on the fly (As it is not created by nsGui but by the
                % user in the experiment file).
                if hasPlugin(plgPanel.UserData.cic,plgPanel.UserData.plugin) || hasStimulus(plgPanel.UserData.cic,plgPanel.UserData.plugin)
                    % The target plugin/stimulus exists.
                    plg= plgPanel.UserData.cic.(plgPanel.UserData.plugin);
                    prop = evt.Source.Tag;
                    if isprop(plg,prop)
                        plg.(prop) = evt.Value;
                        makeSticky(plg,prop);
                    end
                end
            elseif ~isempty(plgPanel.UserData.plugin)
                % Part of the fixed UI of the plugin. The UserData
                % stores a handle to the plugin.
                thisParms = nsGui.getParms(plgPanel);
                guiSet(plgPanel.UserData.plugin,thisParms);
            end

        end


        function  [run,out1,out2] = parse(varargin)
            p=inputParser;
            p.addParameter('do','NODO',@(x) (ischar(x) && ismember(upper(x),{'QRY','RUN'})));
            p.addParameter('modes',{},@iscell);
            p.addParameter('panels',{},@iscell);
            p.addParameter('settings',[]);
            p.addParameter('disabled',{},@iscell);  % Disable some aspects of the CIC interface
            p.addParameter('mode','',@ischar)
            p.addParameter('cic',[]);
            p.KeepUnmatched = true;  % Allow users to add anything(ignored here but could be used in the experiment).
            p.parse(varargin{:});
            out1=[];out2=[];
            switch upper(p.Results.do)
                case 'QRY'
                    % A QRY call from kNeuro gui - it is requesting which plugins should ba
                    % added to the gui
                    out1 = p.Results;
                    run = false;
                    return;
                case 'RUN'
                    % The Run button was pressed in kNeuro
                    %Setup cic with the parameters from the gui
                    run =true;
                    out1 = p.Results.cic;
                    if isempty(p.Results.settings) && ~isempty(p.Results.mode)
                        % A command line call (side-stepping the gui)
                        % This allows users to debug/test without the gui (with: run('do','run','cic',myRig(),'mode','Quest')
                        out2.mode = p.Results.mode;
                    else
                        % A regular call from the GUI
                        out2 = p.Results.settings;
                    end
                case 'NODO'
                    % Called parse without do, probably an attempt to run a gui enabled experiment from the command line.
                    % Start the gui in the folder where the experiment file lives

                    st = dbstack('-completenames');
                    root = fileparts(st(end).file);
                    pthWithoutNs = extractBefore(root,'+');
                    if isempty(pthWithoutNs)
                        % no +
                        pthWithoutNs = root;
                    end
                    nsGui(false,pthWithoutNs);
                    run = false;
                    out1=[];
                    out2=[];
                    %otherwise
                    % Cannot happen
            end

        end

        function parmName = getKey(root,exp,mode,plg,tag,subject)
            if nargin<6
                subject =''; %Allow subject specific
            end
            % Used to store state in a map by creating a unique key
            parmName  =fullfile(root,exp,mode,plg,tag,subject);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, doGitSync, folder)

            app.toggle(false);

            ns = which('neurostim.cic');
            if isempty(ns)
                errordlg('Please add neurostim to your path...');
            else
                neurostimFolder =fileparts(ns);
            end

            app.machine = upper(getenv('COMPUTERNAME'));

            if nargin>1
                if doGitSync
                    app.GitSync.Value = 'On';
                else
                    app.GitSync.Value = 'Off';
                end
                if nargin >2
                    app.Root.Value =folder;
                end
            end
            loadState(app);

            % Bring to front if it already exists
            old = get(0,'ShowHiddenHandles');
            set(0,'ShowHIddenHandles','on');
            h = findobj(0,'Name',app.NeurostimUIFigure.Name);
            if numel(h)==2
                % Delete what we just created and go back to the old one.
                stay = ~cellfun(@isempty,{h.UserData});
                delete (app); % Delete what was just created
                app = h(stay).UserData; % Get the app from the figure UserData
            else
                % New window
                app.NeurostimUIFigure.UserData = app; % Store for easy reference.
            end
            set(0,'ShowHiddenHandles',old);

            %% Make sure we're using the correct Neurostim
            gitcheckout(app,'Neurostim',neurostimFolder);

            % Goto the folder

            gotoFolder(app); % This selects the first experiment
            loadStateExp(app); % This selects a mode.
            loadStateMode(app);
            app.toggle(true);

        end

        % Close request function: NeurostimUIFigure
        function NeurostimUIFigureCloseRequest(app, event)
            sca;
            if strcmpi(event.EventName,'CloseWithoutSave')
                % Do nothing (call from factory reset.)
            else
                try
                    saveState(app);
                catch me
                    me.message
                    disp('Failed to save nsGui state');
                end
            end
            delete(app)

        end

        % Value changed function: Experiments
        function ExperimentsValueChanged(app, event)
            gitcheckout(app,'Experiments',app.Root.Value);
            saveState(app);
        end

        % Value changed function: Neurostim
        function NeurostimValueChanged(app, event)
            ns = which('neurostim.cic');
            neurostimFolder =fileparts(ns);
            gitcheckout(app,'Neurostim',neurostimFolder);
            saveState(app);
        end

        % Menu selected function: EditExperimentFileMenu
        function EditExperimentFileMenuSelected(app, event)
            if ~isempty(app.currentExperimentFile)
                edit(fullfile(app.Root.Value,app.currentExperimentFile));
            end
        end

        % Menu selected function: ChangeRootFolderMenu
        function ChangeRootFolderMenuSelected(app, event)
            folder = uigetdir(app.Root.Value);
            if folder~=0 % folder ==0 is cancel
                gotoFolder(app,folder);
            end
            app.NeurostimUIFigure.Visible = 'Off'; % Toggle to
            app.NeurostimUIFigure.Visible = 'On'; % bring to foreground
        end

        % Menu selected function: ValidateBitsMenu
        function ValidateBitsMenuSelected(app, event)
            bitsppValidate(app,true);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % To save state we need to get the value of the experiment that we just
            % deselected.
            folder = event.OldValue.Title; %
            file = event.OldValue.Children.Value;
            exp  = fullfile(folder,  file);
            saveState(app,exp,'');
            updatePlugins(app);
        end

        % Value changed function: GitSync
        function GitSyncValueChanged(app, event)
            % Chcek that we're synced now.
            if strcmpi(app.GitSync.Value,'On')
                ns = which('neurostim.cic');
                neurostimFolder =fileparts(ns);
                gitcheckout(app,'Neurostim',neurostimFolder);
                gotoFolder(app);
            end
        end

        % Value changed function: Mode
        function ModeValueChanged(app, event)
            saveState(app,'',event.PreviousValue);
            loadStateMode(app,event.Value);

        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % Run neurostim
            app.GoButton.BackgroundColor= 'r';
            app.GoButton.Text  = 'Running...';
            app.GoButton.Enable = false; % Prevent key press interaction
            % Blocking call to run the neurostim application
            try
                % Run setup first(to create a CIC with requested plugins)
                setupCic(app);
                % Call the current experiment file with this CIC and the settings struct with user-specified gui values
                % If the user specified an experiment-specific gui its values will have been stored in .settings.
                app.cic   = app.currentExperiment('do','run','settings',app.settings,'cic',app.cic);
                % Once the experiment completes:
                % Put a handle in the base for easy access.
                assignin('base','c',app.cic);
                if isvalid(app)
                    % Restore not-running state
                    app.cic = []; % No longer running.
                    app.GoButton.BackgroundColor = 'g';
                    app.GoButton.Text = 'Go';
                    app.GoButton.Enable = true;
                end
            catch me
                sca;
                opts.WindowStyle  ='modal';
                opts.Interpreter ='tex';
                if strcmpi(me.message, 'See error message printed above.')
                    msg ='Probably a PTB Synchronization Error. Just try again...';
                else
                    msg = me.message;
                end
                errordlg(char('Experiment failed. \color{red} \bf', msg, '\color{black} \rm \newline See command line for details'),'Experiment failed',opts);
                app.GoButton.BackgroundColor = 'g';
                app.GoButton.Text = 'Go';
                app.GoButton.Enable = true;
                app.cic = []; % No longer running.
                rethrow(me);
            end
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            setPreferences(app);
        end

        % Callback function
        function WildcardValueChanged(app, event)
            gotoFolder(app);
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            doc neurostim
        end

        % Menu selected function: FactoryResetMenu
        function FactoryResetMenuSelected(app, event)
            factoryReset(app);
        end

        % Value changed function: Subject
        function SubjectValueChanged(app, event)
            if app.preferences.PerSubject
                loadStateMode(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeurostimUIFigure = uifigure('Visible', 'off');
            app.NeurostimUIFigure.Name = 'Neurostim';
            app.NeurostimUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeurostimUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.NeurostimUIFigure);
            app.FileMenu.Text = '&File';
            app.ChangeRootFolderMenu = uimenu(app.FileMenu);
            app.ChangeRootFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeRootFolderMenuSelected, true);
            app.ChangeRootFolderMenu.Tooltip = {'Change the folder where nsGui searches for experiments'};
            app.ChangeRootFolderMenu.Accelerator = 'R';
            app.ChangeRootFolderMenu.Text = 'Change &Root Folder';
            app.EditExperimentFileMenu = uimenu(app.FileMenu);
            app.EditExperimentFileMenu.MenuSelectedFcn = createCallbackFcn(app, @EditExperimentFileMenuSelected, true);
            app.EditExperimentFileMenu.Tooltip = {'Edit the current experiment file.'};
            app.EditExperimentFileMenu.Accelerator = 'E';
            app.EditExperimentFileMenu.Text = '&Edit Experiment File';
            app.ValidateBitsMenu = uimenu(app.FileMenu);
            app.ValidateBitsMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidateBitsMenuSelected, true);
            app.ValidateBitsMenu.Tooltip = {'Run validation for Bits++.'};
            app.ValidateBitsMenu.Text = 'Validate Bits++';
            app.PreferencesMenu = uimenu(app.FileMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Tooltip = {'Show and set application preferences.'};
            app.PreferencesMenu.Accelerator = 'P';
            app.PreferencesMenu.Text = '&Preferences';
            app.FactoryResetMenu = uimenu(app.FileMenu);
            app.FactoryResetMenu.MenuSelectedFcn = createCallbackFcn(app, @FactoryResetMenuSelected, true);
            app.FactoryResetMenu.Tooltip = {'Press to forget stored settings for experiments, folders, etc.  (Will close the gui too)'};
            app.FactoryResetMenu.Accelerator = 'F';
            app.FactoryResetMenu.Text = '&Factory Reset';
            app.HelpMenu = uimenu(app.FileMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Tooltip = {'Open the documentation in a browser.'};
            app.HelpMenu.Accelerator = 'h';
            app.HelpMenu.Text = 'Help';
            app.GitSettingsPanel = uipanel(app.NeurostimUIFigure);
            app.GitSettingsPanel.Title = 'Git Settings';
            app.ExperimentsEditFieldLabel = uilabel(app.GitSettingsPanel);
            app.ExperimentsEditFieldLabel.Text = 'Experiments';
            app.Experiments = uieditfield(app.GitSettingsPanel, 'text');
            app.Experiments.ValueChangedFcn = createCallbackFcn(app, @ExperimentsValueChanged, true);
            app.Experiments.Tooltip = {'The git branch in use for the root folder.'};
            app.Experiments.Value = 'master';
            app.NeurostimEditFieldLabel = uilabel(app.GitSettingsPanel);
            app.NeurostimEditFieldLabel.Text = 'Neurostim';
            app.Neurostim = uieditfield(app.GitSettingsPanel, 'text');
            app.Neurostim.ValueChangedFcn = createCallbackFcn(app, @NeurostimValueChanged, true);
            app.Neurostim.Tooltip = {'The git branch in use for the core neurostim toolbox.'};
            app.Neurostim.Value = 'master';
            app.GitSync = uiswitch(app.GitSettingsPanel, 'slider');
            app.GitSync.ValueChangedFcn = createCallbackFcn(app, @GitSyncValueChanged, true);
            app.GitSync.Tooltip = {'When On'; ' git branches are pulled and committed before each experiment.'};
            app.SilentCommit = uicheckbox(app.GitSettingsPanel);
            app.SilentCommit.Tooltip = {'Check to skip entering commit messages.'};
            app.SilentCommit.Text = 'Silent Commit';
            app.Status = uilabel(app.NeurostimUIFigure);
            app.Status.Text = 'Status';
            app.TabGroup = uitabgroup(app.NeurostimUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.placeholder = uitab(app.TabGroup);
            app.placeholder.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.PluginSettingsPanel = uipanel(app.NeurostimUIFigure);
            app.PluginSettingsPanel.Tooltip = {'Plugins and Stimuli (as requested by the experiment file) add their gui panels here.'};
            app.PluginSettingsPanel.Title = 'Plugin Settings';
            app.GoButton = uibutton(app.NeurostimUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Tooltip = {'Press to start or stop the experiment.'};
            app.GoButton.Text = 'Go';
            app.ExperimentSettingsPanel = uipanel(app.NeurostimUIFigure);
            app.ExperimentSettingsPanel.Title = 'Experiment Settings';
            app.ModeDropDownLabel = uilabel(app.ExperimentSettingsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.Mode = uidropdown(app.ExperimentSettingsPanel);
            app.Mode.Items = {};
            app.Mode.ValueChangedFcn = createCallbackFcn(app, @ModeValueChanged, true);
            app.Mode.Tooltip = {'Modes as defined by the currently selected experiment.'};
            app.Mode.Value = {};
            app.RunEditFieldLabel = uilabel(app.ExperimentSettingsPanel);
            app.RunEditFieldLabel.Text = 'Run #';
            app.Run = uieditfield(app.ExperimentSettingsPanel, 'numeric');
            app.Run.Tooltip = {'Runs refer to the same subject running the same experiment multiple times.'};
            app.SeqLabel = uilabel(app.ExperimentSettingsPanel);
            app.SeqLabel.Text = 'Seq #';
            app.Seq = uieditfield(app.ExperimentSettingsPanel, 'numeric');
            app.Seq.Tooltip = {'Subjects running an experiment are numbered sequentially to get a sequence number'; ''};
            app.SubjectEditFieldLabel = uilabel(app.ExperimentSettingsPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.Subject = uieditfield(app.ExperimentSettingsPanel, 'text');
            app.Subject.ValueChangedFcn = createCallbackFcn(app, @SubjectValueChanged, true);
            app.Subject.Tooltip = {'The subject number or iinitials.'};
            app.Subject.Value = '0';
            app.Debug = uicheckbox(app.ExperimentSettingsPanel);
            app.Debug.Tooltip = {'Check to run in debug mode (interpreted in the My Rig function only)'};
            app.Debug.Text = 'Debug';
            app.Root = uieditfield(app.NeurostimUIFigure, 'text');
            app.Root.Editable = 'off';
            app.Root.Tooltip = {'The folder that  is scanned recursively for experiment files .'};
            app.ProjectExperimentsLabel = uilabel(app.NeurostimUIFigure);
            app.ProjectExperimentsLabel.Text = 'Project/Experiments';
            % Show the figure after all components are created
            app.NeurostimUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nsGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeurostimUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeurostimUIFigure)
        end
    end
end